classdef DOANPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TmNghimTab                    matlab.ui.container.Tab
        Label7_6                      matlab.ui.control.Label
        SaiSoEditField                matlab.ui.control.EditField
        NhpGiSaiSChoPhpLabel          matlab.ui.control.Label
        NhpGiTrbEditField             matlab.ui.control.EditField
        NhpGiTrbEditFieldLabel        matlab.ui.control.Label
        NhpGiTraEditField             matlab.ui.control.EditField
        NhpGiTraEditFieldLabel        matlab.ui.control.Label
        NhpPhngTrnhFpxEditField       matlab.ui.control.EditField
        NhpPhngTrnhFpxEditFieldLabel  matlab.ui.control.Label
        PhuongTrinhEditField          matlab.ui.control.EditField
        NhpphngtrnhfxLabel            matlab.ui.control.Label
        PhngphpButtonGroup            matlab.ui.container.ButtonGroup
        NewtonButton                  matlab.ui.control.RadioButton
        LapButton                     matlab.ui.control.RadioButton
        CdButton                      matlab.ui.control.RadioButton
        KtQuNghimLabel                matlab.ui.control.Label
        SoLanLapEditField             matlab.ui.control.EditField
        KetQuaEditField               matlab.ui.control.EditField
        SLnLpLabel                    matlab.ui.control.Label
        KetQuaButton                  matlab.ui.control.Button
        UIAxes_3                      matlab.ui.control.UIAxes
        HiQuyTab                      matlab.ui.container.Tab
        TinhVeButton_2                matlab.ui.control.Button
        Label7_5                      matlab.ui.control.Label
        ketquaEditField               matlab.ui.control.NumericEditField
        KtQuLabel                     matlab.ui.control.Label
        dathucEditField               matlab.ui.control.EditField
        athchiquyEditFieldLabel       matlab.ui.control.Label
        gtriXEditField                matlab.ui.control.NumericEditField
        NhpGiTrxLabel                 matlab.ui.control.Label
        nhapmangY                     matlab.ui.control.EditField
        NhpDLiuyLabel                 matlab.ui.control.Label
        nhapmangX                     matlab.ui.control.EditField
        NhpDLiuxLabel                 matlab.ui.control.Label
        PhngPhpNiSuyButtonGroup       matlab.ui.container.ButtonGroup
        hammuButton                   matlab.ui.control.RadioButton
        hamemuButton                  matlab.ui.control.RadioButton
        TuyentinhButton               matlab.ui.control.RadioButton
        UIAxes                        matlab.ui.control.UIAxes
        NiSuyTab                      matlab.ui.container.Tab
        Label7_2                      matlab.ui.control.Label
        KQDaThuc                      matlab.ui.control.EditField
        KtQuaThcNiSuyEditFieldLabel   matlab.ui.control.Label
        KQ                            matlab.ui.control.NumericEditField
        KtQuNiSuyEditFieldLabel       matlab.ui.control.Label
        Nhapx                         matlab.ui.control.NumericEditField
        NhpGiTrCnNiSuyLabel           matlab.ui.control.Label
        mangx_2                       matlab.ui.control.EditField
        NhpmngxLabel                  matlab.ui.control.Label
        mangy_2                       matlab.ui.control.EditField
        NhpmngyLabel                  matlab.ui.control.Label
        Chon                          matlab.ui.container.ButtonGroup
        LagrangeButton                matlab.ui.control.RadioButton
        NewtonButton_2                matlab.ui.control.RadioButton
        KQDaThucLabel                 matlab.ui.control.Label
        TinhVeButton                  matlab.ui.control.Button
        UIAxes_4                      matlab.ui.control.UIAxes
        oHmTab                        matlab.ui.container.Tab
        CniviPPCentralNuchnsaislOh2thchn1NuchnsaislOh4thchn2Label  matlab.ui.control.Label
        Label7_4                      matlab.ui.control.Label
        textResult                    matlab.ui.control.TextArea
        KtquLabel                     matlab.ui.control.Label
        xInput                        matlab.ui.control.EditField
        NhpdliuxLabel                 matlab.ui.control.Label
        evaluationPointInput          matlab.ui.control.NumericEditField
        GitrcntnhLabel                matlab.ui.control.Label
        methodChoice                  matlab.ui.control.DropDown
        ChnPPohmLabel                 matlab.ui.control.Label
        accuracyChoice                matlab.ui.control.DropDown
        GitrsaisLabel                 matlab.ui.control.Label
        inputChoice                   matlab.ui.control.DropDown
        InputChoiceLabel              matlab.ui.control.Label
        hInput                        matlab.ui.control.NumericEditField
        NhpbchLabel                   matlab.ui.control.Label
        functionInput                 matlab.ui.control.EditField
        FunctionInputLabel            matlab.ui.control.Label
        fxInput                       matlab.ui.control.EditField
        NhpLabel                      matlab.ui.control.Label
        LuNuchnsaislOhthchn1NuchnsaislOh2thchn2Label  matlab.ui.control.Label
        ThcHinPhpTnhButton            matlab.ui.control.Button
        TchPhnTab                     matlab.ui.container.Tab
        TnhButton                     matlab.ui.control.Button
        khoangchia                    matlab.ui.control.EditField
        Label9                        matlab.ui.control.Label
        canduoi                       matlab.ui.control.EditField
        Label8                        matlab.ui.control.Label
        cantren                       matlab.ui.control.EditField
        LabelEditField7               matlab.ui.control.Label
        mangy                         matlab.ui.control.EditField
        Label6                        matlab.ui.control.Label
        mangx                         matlab.ui.control.EditField
        LabelEditField                matlab.ui.control.Label
        nghiem                        matlab.ui.control.EditField
        LabelEditField6               matlab.ui.control.Label
        Hamso                         matlab.ui.control.EditField
        Label2                        matlab.ui.control.Label
        Label7                        matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        Simpson38                     matlab.ui.control.RadioButton
        Simpson13                     matlab.ui.control.RadioButton
        Hinhthang                     matlab.ui.control.RadioButton
        Label3                        matlab.ui.control.Label
        GiiThiuNhmTab                 matlab.ui.container.Tab
        diem                          matlab.ui.control.EditField
        imTnhGiLabel                  matlab.ui.control.Label
        TmThngTinButton               matlab.ui.control.Button
        Label7_3                      matlab.ui.control.Label
        cMNguynVngEditField           matlab.ui.control.EditField
        cMNguynVngEditFieldLabel      matlab.ui.control.Label
        imMnhEditField                matlab.ui.control.EditField
        imMnhEditFieldLabel           matlab.ui.control.Label
        GmailcnhnEditField            matlab.ui.control.EditField
        GmailcnhnEditFieldLabel       matlab.ui.control.Label
        SThchEditField                matlab.ui.control.EditField
        SThchEditFieldLabel           matlab.ui.control.Label
        nhiDinLabel                   matlab.ui.control.Label
        mssv                          matlab.ui.control.EditField
        MSSinhVinEditFieldLabel       matlab.ui.control.Label
        namsinh                       matlab.ui.control.EditField
        NmSinhEditFieldLabel          matlab.ui.control.Label
        nhiemvu                       matlab.ui.control.EditField
        PhnCngNhimvEditFieldLabel     matlab.ui.control.Label
        quequan                       matlab.ui.control.EditField
        QuQunEditFieldLabel           matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ThnhVinNhmButtonGroup         matlab.ui.container.ButtonGroup
        LT                            matlab.ui.control.RadioButton
        TVT                           matlab.ui.control.RadioButton
        NTL                           matlab.ui.control.RadioButton
        VBN                           matlab.ui.control.RadioButton
        NTPQ                          matlab.ui.control.RadioButton
        LVM                           matlab.ui.control.RadioButton
    end

    
    methods (Access = private)
          % Phương pháp chia đôi
      function [nghiem, solanlap] = chiadoi(~, f, a, b, saiso)
    solanlap = 0;
 while abs(b-a) >= saiso
     solanlap = solanlap + 1;
     c = (a+b)/2;
 if f(c)*f(a) < 0
    b = c;
 elseif f(c)*f(a) > 0
    a = c;
 else
 break;
 end
 end
     nghiem = c;
 end
        % Phương pháp lặp
function [nghiem, solanlap] = lap(~,fp,a,b,saiso)
     x0 = a;
     nghiem = b;
     solanlap = 0;
 while abs(nghiem - x0) >= saiso
     solanlap = solanlap + 1;
     x0 = nghiem;
     nghiem = fp(x0);
 end
end
    
    %Phương pháp tiếp tuyến
function [nghiem,solanlap] = tieptuyen(~,f,a,b,saiso)
 syms x;
     fd1 = str2func(['@(x)' char(diff(f(x)))]);
     fd2 = str2func(['@(x)' char(diff(fd1(x)))]);
     nostop = 1;
 if nostop
    x0 = a;
 while f(x0)*fd2(x0) <= 0
 if f(x0) == 0
     nghiem = x0;
     solanlap = 0;
 return;
 else
      x0 = (x0+b)/2;
 end
 end
      nghiem = x0 - f(x0)/fd1(x0);
      solanlap = 0;
 while abs(nghiem-x0) >= saiso
      solanlap = solanlap + 1;
      x0 = nghiem;
      nghiem = x0 - f(x0)/fd1(x0);
 end
 end
 end
   
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ButtonGroup, TnhButton
        function ButtonGroupSelectionChanged(app, event)
             selectedButton = app.ButtonGroup.SelectedObject;
                    xa = app.mangx.Value;
                    xa = str2num(xa);
                    ya = app.mangy.Value;
                    ya = str2num(ya);
                    b = app.cantren.Value;
                    b = str2num(b);
                    a = app.canduoi.Value;
                    a = str2num(a);
                    N = app.khoangchia.Value;
                    N = str2num(N);
            if app.Hinhthang.Value == 1
                    if xa ~= NaN & ya ~= NaN
                        KQ = trapz(xa,ya);
                    else
                       fx = app.Hamso.Value;
                       fx = str2func(['@(x)',fx]);
                       [KQ] = tichphanhinhthang (fx, a, b, N)
                    end
                    app.nghiem.Value = num2str(KQ);
            
            elseif app.Simpson13.Value == 1
                    if xa ~= NaN & ya ~= NaN
                        KQ = trapz(xa,ya);
                    else
                    fx = app.Hamso.Value;
                    fx = str2func(['@(x)',fx]);
                        if mod(N,2)~=0
                        app.nghiem.Value = char('Sai khoang chia');
                        return
                        end
                    [KQ] = tichphanSimpson(fx, a, b, N)
                    end
                    app.nghiem.Value = num2str(KQ);
        
            elseif app.Simpson38.Value == 1
                    if xa ~= NaN & ya ~= NaN
                        KQ = trapz(xa,ya);
                    else
                    fx = app.Hamso.Value;
                    fx = str2func(['@(x)',fx]); 
                    if mod(N,3)~=0
                        app.nghiem.Value = char('Sai khoang chia');
                        return
                    end
                    [KQ] = tichphanSimpson38(fx, a, b, N)
                    end
                    app.nghiem.Value = num2str(KQ);
            end
        end

        % Callback function: PhngPhpNiSuyButtonGroup, TinhVeButton_2
        function PhngPhpNiSuyButtonGroupSelectionChanged(app, event)
            selectedButton = app.PhngPhpNiSuyButtonGroup.SelectedObject;
             x_array = str2num(app.nhapmangX.Value);
           y_array = str2num(app.nhapmangY.Value);
           giatrix = app.gtriXEditField.Value;
           
           if(app.TuyentinhButton.Value == 1)
               [fx, kq] = Hoiquybac1(x_array, y_array, giatrix);
           elseif(app.hamemuButton.Value == 1)
               [fx, kq] = Hoiquyemu(x_array, y_array, giatrix);
           else
               [fx, kq] = Hoiquyhammu(x_array, y_array, giatrix);
           end
           fx1 = str2func(['@(x)', char(fx)]);
           x = x_array(1):0.1:x_array(length(x_array));
           j = 1;
           for i = x_array(1):0.1:x_array(length(x_array))
               y(j) = fx1(i);
               j = j+1;
           end
           plot(app.UIAxes, x, y);
           app.dathucEditField.Value = char(fx);
           if isnumeric(kq) && isscalar(kq) % Check if kq is a numeric scalar
               app.ketquaEditField.Value = double(kq);
           elseif ischar(kq) % Check if kq is a character vector
               app.ketquaEditField.Value = kq;
           else
               % Handle other cases or display an error message
               disp('Error: Unsupported data type for kq');
           end
        end

        % Callback function
        function TinhVeButtonPushed(app, event)
             chon = app.Chon.Value;
            xa = str2num(app.mangx_2.Value);
            ya = str2num(app.mangy_2.Value);
            x  = app.Nhapx.Value;
            if (chon ==  "Nội suy Newton")
                [result] = NewtonInterpolation(xa, ya, x);
                app.KQ.Text = num2str(result);
                plot(app.UIAxes_2,xa,ya);  
            elseif (chon == "Nội suy Lagrange")
                [result] = Lagrange(xa, ya, x);
                app.KQ.Text = num2str(result);
                plot(app.UIAxes_2,xa,ya);
            end
        end

        % Button pushed function: ThcHinPhpTnhButton
        function ThcHinPhpTnhButtonPushed(app, event)
            
   
    % Get input values from UI components
    if strcmp(app.inputChoice.Value, 'ValuesXY')
        x = str2num(app.xInput.Value);
        fx = str2num(app.fxInput.Value);
        order_of_accuracy = str2num(app.accuracyChoice.Value);
        method = app.methodChoice.Value;
        evaluation_point = app.evaluationPointInput.Value;


	% Calculate derivative based on chosen method
    if strcmp(method, 'Forward')
        derivative = forwardDifferenceDerivativexy(x, fx, evaluation_point, order_of_accuracy);
    elseif strcmp(method, 'Backward')
        derivative = backwardDifferenceDerivativeDerivativexy(x, fx, evaluation_point, order_of_accuracy);
    elseif strcmp(method, 'Central')
        derivative = centralDifferenceDerivativeDerivativexy(x, fx, evaluation_point, order_of_accuracy);
    else
        error('Invalid method selection.');
    end


    % Display the result
    app.textResult.Value = sprintf('Đạo hàm tại %.2f là: %.4f', evaluation_point, derivative);

    else

        func_str = app.functionInput.Value;
        func = str2func(['@(x)' func_str]); % Thêm @(x) để tạo hàm số từ chuỗi
        
        h = app.hInput.Value;
        order_of_accuracy = str2num(app.accuracyChoice.Value);
        method = app.methodChoice.Value;
        evaluation_point = app.evaluationPointInput.Value;


% Calculate derivative based on chosen method
    if strcmp(method, 'Forward')
        derivative = forwardDifferenceDerivative(func, evaluation_point, h, order_of_accuracy);
    elseif strcmp(method, 'Backward')
        derivative = backwardDifferenceDerivative(func, evaluation_point, h, order_of_accuracy);
    elseif strcmp(method, 'Central')
        derivative = centralDifferenceDerivative(func, evaluation_point, h, order_of_accuracy);
    else
        error('Invalid method selection.');
    end

    % Display the result
    app.textResult.Value = sprintf('Đạo hàm tại %.2f là: %.4f', evaluation_point, derivative);
    
    end
        end

        % Callback function: KetQuaButton, PhngphpButtonGroup
        function KetQuaButtonPushed(app, event)

 chiadoi = app.CdButton.Value;
 lap = app.LapButton.Value;
 newton = app.NewtonButton.Value;

 f = app.PhuongTrinhEditField.Value;
 f = str2func(['@(x)' f]);

 fp = app.NhpPhngTrnhFpxEditField.Value;
 fp = str2func(['@(x)' fp]);

 a = app.NhpGiTraEditField.Value;
 a = str2num(a);

 b = app.NhpGiTrbEditField.Value;
 b = str2num(b);

 saiso = app.SaiSoEditField.Value;
 saiso = str2num(saiso);

 x_array = linspace(a,b);
 y_array = zeros(1, length(x_array));
 yp_array = zeros(1, length(x_array));

 for i = 1:length(x_array) - 1
 y_array(i) = f(x_array(i));
 yp_array(i) = fp(x_array(i));
 end

 if chiadoi == 1
     [nghiem, solanlap] = app.chiadoi(f, a, b, saiso)
     app.KetQuaEditField.Value = num2str(nghiem);
     app.SoLanLapEditField.Value = num2str(solanlap);
     plot(app.UIAxes_3,x_array, y_array);
     title(app.UIAxes_3, 'Phương pháp chia đôi');
 elseif lap == 1
     [nghiem, solanlap] = app.lap(fp,a,b,saiso);
     app.KetQuaEditField.Value = num2str(nghiem);
     app.SoLanLapEditField.Value = num2str(solanlap);
     plot(app.UIAxes_3,x_array, yp_array);
     title(app.UIAxes_3, 'Phương pháp lặp');
 else
     [nghiem,solanlap] = app.tieptuyen(f,a,b,saiso);
     app.KetQuaEditField.Value = num2str(nghiem);
     app.SoLanLapEditField.Value = num2str(solanlap);
     plot(app.UIAxes_3,x_array, y_array);
     title(app.UIAxes_3, 'Phương pháp tiếp tuyến');
 end

        end

        % Callback function: ThnhVinNhmButtonGroup, TmThngTinButton
        function ThnhVinNhmButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThnhVinNhmButtonGroup.SelectedObject;
            if app.LVM.Value == 1
                    app.mssv.Value = '21200138';
                    app.quequan.Value = 'Quảng Ngãi';
                    app.namsinh.Value = '17/10/2003';
                    app.nhiemvu.Value = 'Thiết kế tab giới thiệu nhóm, Thiết kế app hoàn chỉnh, Viết báo cáo word';
                    app.Image.ImageSource = 'LM.JPG';
                    app.SThchEditField.Value = 'Tôi yêu tiền tiền đến với tôi';
                    app.imMnhEditField.Value = 'Siêu cấp báo thủ';
                    app.cMNguynVngEditField.Value = 'Trở thành tổng tài siêu đẹp trai và Được 10 điểm của Thầy Thịnh';
                    app.GmailcnhnEditField.Value = '21200138@student.hcmus.edu.vn';
                    app.diem.Value= '10'
            end
            if app.NTPQ.Value == 1
                    app.mssv.Value = '21200196';
                    app.quequan.Value = 'Bình Định';
                    app.namsinh.Value = '2003';
                    app.nhiemvu.Value = 'Thiết kế tab tích Phân';
                    app.Image.ImageSource = 'PQ.JPG';
                    app.SThchEditField.Value = 'Đọc truyện, nghe nhạc';
                    app.imMnhEditField.Value = 'Chịu khó, siêng năng';
                    app.cMNguynVngEditField.Value = 'Giàu và đẹp như Kim Jisoo';
                    app.GmailcnhnEditField.Value = 'phuongquynh722003@gmail.com';
                       app.diem.Value= '9.83'
            end
            if app.NTL.Value == 1
                    app.mssv.Value = '21200309';
                    app.quequan.Value = 'Phú Thọ';
                    app.namsinh.Value = '2003';
                    app.nhiemvu.Value = 'Thiết kế tab Đạo Hàm';
                    app.Image.ImageSource = 'TL.JPG';
                    app.SThchEditField.Value = 'Xem phim';
                    app.imMnhEditField.Value = 'Ngủ';
                    app.cMNguynVngEditField.Value = 'Qua tất cả các môn';
                    app.GmailcnhnEditField.Value = 'locnguyenthanh203@gmail.com';
                       app.diem.Value= '9.67'
            end
            if app.TVT.Value == 1
                    app.mssv.Value = '21200350';
                    app.quequan.Value = 'TP.HCM';
                    app.namsinh.Value = '2003';
                    app.nhiemvu.Value = 'Thiết kế tab Nội Suy';
                    app.Image.ImageSource = 'VT.JPG';
                    app.SThchEditField.Value = 'Không';
                    app.imMnhEditField.Value = 'Không';
                    app.cMNguynVngEditField.Value = 'Học thật giỏi chuyên ngành Viễn Thông - Mạng';
                    app.GmailcnhnEditField.Value = 'vythanhpch99@gmail.com';
                       app.diem.Value= '8.83'
            end
            if app.VBN.Value == 1
                    app.mssv.Value = '21200152';
                    app.quequan.Value = 'Bình Định';
                    app.namsinh.Value = '2003';
                    app.nhiemvu.Value = 'Thiết kế tab Hồi Quy';
                    app.Image.ImageSource = 'BN.JPG';
                    app.SThchEditField.Value = 'Đọc truyện';
                    app.imMnhEditField.Value = 'Ngủ';
                    app.cMNguynVngEditField.Value = '10 điểm đồ án thầy Thịnh';
                    app.GmailcnhnEditField.Value = 'ngocbichvo2003@gmail.com';
                       app.diem.Value= '9.5'
            end
            if app.LT.Value == 1
                    app.mssv.Value = '21200367';
                    app.quequan.Value = 'Hải Lăng - Quảng Trị';
                    app.namsinh.Value = '2003';
                    app.nhiemvu.Value = 'Thiết kế tab Tìm Nghiệm';
                    app.Image.ImageSource = 'LT.JPG';
                    app.SThchEditField.Value = 'Game, đọc truyện';
                    app.imMnhEditField.Value = 'Lười';
                    app.cMNguynVngEditField.Value = 'A+ ppt';
                    app.GmailcnhnEditField.Value = 'LeTuan752003@gmail.com';
                       app.diem.Value= '9.17'
            end
        end

        % Callback function: Chon, TinhVeButton
        function TinhVeButtonPushed2(app, event)
             xa = str2num(app.mangx_2.Value);
            ya = str2num(app.mangy_2.Value);
            x  = app.Nhapx.Value;
            if(app.NewtonButton_2.Value == 1)
                [result] = NewtonInterpolation(xa, ya, x);
                app.KQ.Value = result;
                [p] = NewtonInterpolation_sym(xa, ya, x);
                app.KQDaThuc.Value = func2str(matlabFunction(p));
                plot(app.UIAxes_4,xa,ya);  
            elseif(app.LagrangeButton.Value == 1)
                [result] = Lagrange(xa, ya, x);
                app.KQ.Value = result;
                [p] = Lagrange_sym(xa, ya, x);
                app.KQDaThuc.Value = func2str(matlabFunction(p));
                plot(app.UIAxes_4,xa,ya);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TmNghimTab = uitab(app.TabGroup);
            app.TmNghimTab.Title = 'Tìm Nghiệm';
            app.UIAxes_3 = uiaxes(app.TmNghimTab);
            title(app.UIAxes_3, 'Đồ Thị')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.KetQuaButton = uibutton(app.TmNghimTab, 'push');
            app.KetQuaButton.ButtonPushedFcn = createCallbackFcn(app, @KetQuaButtonPushed, true);
            app.KetQuaButton.Text = {'Kết quả nghiệm và vẽ'; ''};
            app.SLnLpLabel = uilabel(app.TmNghimTab);
            app.SLnLpLabel.Text = 'Số Lần Lặp';
            app.KetQuaEditField = uieditfield(app.TmNghimTab, 'text');
            app.SoLanLapEditField = uieditfield(app.TmNghimTab, 'text');
            app.KtQuNghimLabel = uilabel(app.TmNghimTab);
            app.KtQuNghimLabel.Text = 'Kết Quả Nghiệm';
            app.PhngphpButtonGroup = uibuttongroup(app.TmNghimTab);
            app.PhngphpButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KetQuaButtonPushed, true);
            app.PhngphpButtonGroup.Title = 'Phương pháp';
            app.CdButton = uiradiobutton(app.PhngphpButtonGroup);
            app.CdButton.Text = 'Chia đôi';
            app.CdButton.Value = true;
            app.LapButton = uiradiobutton(app.PhngphpButtonGroup);
            app.LapButton.Text = {'Lặp'; ''};
            app.NewtonButton = uiradiobutton(app.PhngphpButtonGroup);
            app.NewtonButton.Text = {'Newton'; ''};
            app.NhpphngtrnhfxLabel = uilabel(app.TmNghimTab);
            app.NhpphngtrnhfxLabel.Text = 'Nhập Phương Trình F(x)';
            app.PhuongTrinhEditField = uieditfield(app.TmNghimTab, 'text');
            app.PhuongTrinhEditField.Value = 'x-sin(x)-0.25';
            app.NhpPhngTrnhFpxEditFieldLabel = uilabel(app.TmNghimTab);
            app.NhpPhngTrnhFpxEditFieldLabel.Text = 'Nhập Phương Trình Fp(x)';
            app.NhpPhngTrnhFpxEditField = uieditfield(app.TmNghimTab, 'text');
            app.NhpPhngTrnhFpxEditField.Value = 'sin(x)+0.25';
            app.NhpGiTraEditFieldLabel = uilabel(app.TmNghimTab);
            app.NhpGiTraEditFieldLabel.Text = 'Nhập Giá Trị a';
            app.NhpGiTraEditField = uieditfield(app.TmNghimTab, 'text');
            app.NhpGiTraEditField.Value = '-1';
            app.NhpGiTrbEditFieldLabel = uilabel(app.TmNghimTab);
            app.NhpGiTrbEditFieldLabel.Text = 'Nhập Giá Trị b';
            app.NhpGiTrbEditField = uieditfield(app.TmNghimTab, 'text');
            app.NhpGiTrbEditField.Value = '2';
            app.NhpGiSaiSChoPhpLabel = uilabel(app.TmNghimTab);
            app.NhpGiSaiSChoPhpLabel.Text = 'Nhập Giá Sai Số Cho Phép';
            app.SaiSoEditField = uieditfield(app.TmNghimTab, 'text');
            app.SaiSoEditField.Value = '0.05';
            app.Label7_6 = uilabel(app.TmNghimTab);
            app.Label7_6.Text = 'Giao Diện Tìm Nghiệm ';
            app.HiQuyTab = uitab(app.TabGroup);
            app.HiQuyTab.Title = 'Hồi Quy';
            app.UIAxes = uiaxes(app.HiQuyTab);
            title(app.UIAxes, 'Đồ Thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PhngPhpNiSuyButtonGroup = uibuttongroup(app.HiQuyTab);
            app.PhngPhpNiSuyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhngPhpNiSuyButtonGroupSelectionChanged, true);
            app.PhngPhpNiSuyButtonGroup.Title = 'Phương Pháp Nội Suy';
            app.TuyentinhButton = uiradiobutton(app.PhngPhpNiSuyButtonGroup);
            app.TuyentinhButton.Text = 'Tuyến Tính';
            app.TuyentinhButton.Value = true;
            app.hamemuButton = uiradiobutton(app.PhngPhpNiSuyButtonGroup);
            app.hamemuButton.Text = 'Hàm e Mũ';
            app.hammuButton = uiradiobutton(app.PhngPhpNiSuyButtonGroup);
            app.hammuButton.Text = 'Hàm Mũ';
            app.NhpDLiuxLabel = uilabel(app.HiQuyTab);
            app.NhpDLiuxLabel.Text = 'Nhập Dữ Liệu x';
            app.nhapmangX = uieditfield(app.HiQuyTab, 'text');
            app.nhapmangX.Value = '1 2 3 4';
            app.NhpDLiuyLabel = uilabel(app.HiQuyTab);
            app.NhpDLiuyLabel.Text = 'Nhập Dữ Liệu y';
            app.nhapmangY = uieditfield(app.HiQuyTab, 'text');
            app.nhapmangY.Value = '2  4 8 16';
            app.NhpGiTrxLabel = uilabel(app.HiQuyTab);
            app.NhpGiTrxLabel.Text = 'Nhập Giá Trị x';
            app.gtriXEditField = uieditfield(app.HiQuyTab, 'numeric');
            app.gtriXEditField.Value = 3.2;
            app.athchiquyEditFieldLabel = uilabel(app.HiQuyTab);
            app.athchiquyEditFieldLabel.Text = 'Đa thức hồi quy';
            app.dathucEditField = uieditfield(app.HiQuyTab, 'text');
            app.KtQuLabel = uilabel(app.HiQuyTab);
            app.KtQuLabel.Text = 'Kết Quả';
            app.ketquaEditField = uieditfield(app.HiQuyTab, 'numeric');
            app.Label7_5 = uilabel(app.HiQuyTab);
            app.Label7_5.Text = 'Giao Diện Hồi Quy ';
            app.TinhVeButton_2 = uibutton(app.HiQuyTab, 'push');
            app.TinhVeButton_2.ButtonPushedFcn = createCallbackFcn(app, @PhngPhpNiSuyButtonGroupSelectionChanged, true);
            app.TinhVeButton_2.Text = 'Kết quả nghiệm và vẽ';
            app.NiSuyTab = uitab(app.TabGroup);
            app.NiSuyTab.Title = 'Nội Suy';
            app.UIAxes_4 = uiaxes(app.NiSuyTab);
            title(app.UIAxes_4, 'Đồ Thị')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.TinhVeButton = uibutton(app.NiSuyTab, 'push');
            app.TinhVeButton.ButtonPushedFcn = createCallbackFcn(app, @TinhVeButtonPushed2, true);
            app.TinhVeButton.Text = 'Kết quả nghiệm và vẽ';
            app.KQDaThucLabel = uilabel(app.NiSuyTab);
            app.KQDaThucLabel.Text = '';
            app.Chon = uibuttongroup(app.NiSuyTab);
            app.Chon.SelectionChangedFcn = createCallbackFcn(app, @TinhVeButtonPushed2, true);
            app.Chon.Title = 'Chọn Phương Pháp';
            app.NewtonButton_2 = uiradiobutton(app.Chon);
            app.NewtonButton_2.Text = 'Nội Suy Newton';
            app.NewtonButton_2.Value = true;
            app.LagrangeButton = uiradiobutton(app.Chon);
            app.LagrangeButton.Text = 'Nội Suy Lagrange';
            app.NhpmngyLabel = uilabel(app.NiSuyTab);
            app.NhpmngyLabel.Text = 'Nhập Mảng y';
            app.mangy_2 = uieditfield(app.NiSuyTab, 'text');
            app.mangy_2.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpmngxLabel = uilabel(app.NiSuyTab);
            app.NhpmngxLabel.Text = 'Nhập Mảng x';
            app.mangx_2 = uieditfield(app.NiSuyTab, 'text');
            app.mangx_2.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpGiTrCnNiSuyLabel = uilabel(app.NiSuyTab);
            app.NhpGiTrCnNiSuyLabel.Text = {'Nhập Giá Trị'; 'Cần Nội Suy'};
            app.Nhapx = uieditfield(app.NiSuyTab, 'numeric');
            app.Nhapx.Value = 0.14;
            app.KtQuNiSuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.KtQuNiSuyEditFieldLabel.Text = 'Kết Quả Nội Suy:';
            app.KQ = uieditfield(app.NiSuyTab, 'numeric');
            app.KtQuaThcNiSuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.KtQuaThcNiSuyEditFieldLabel.Text = 'Kết Quả Đa Thức Nội Suy ';
            app.KQDaThuc = uieditfield(app.NiSuyTab, 'text');
            app.Label7_2 = uilabel(app.NiSuyTab);
            app.Label7_2.Text = 'Giao Diện Tính Nội Suy ';
            app.oHmTab = uitab(app.TabGroup);
            app.oHmTab.Title = 'Đạo Hàm';
            app.ThcHinPhpTnhButton = uibutton(app.oHmTab, 'push');
            app.ThcHinPhpTnhButton.ButtonPushedFcn = createCallbackFcn(app, @ThcHinPhpTnhButtonPushed, true);
            app.ThcHinPhpTnhButton.Text = 'Thực Hiện Phép Tính';
            app.LuNuchnsaislOhthchn1NuchnsaislOh2thchn2Label = uilabel(app.oHmTab);
            app.LuNuchnsaislOhthchn1NuchnsaislOh2thchn2Label.Text = {'Lưu ý : đối với PP Forward và Backward'; ' - Nếu chọn sai số là O(h) thì chọn 1'; ' - Nếu chọn sai số là O(h^2) thì chọn 2'};
            app.NhpLabel = uilabel(app.oHmTab);
            app.NhpLabel.Text = 'Nhập Dữ Liệu y';
            app.fxInput = uieditfield(app.oHmTab, 'text');
            app.fxInput.Value = '2 4 8 16';
            app.FunctionInputLabel = uilabel(app.oHmTab);
            app.FunctionInputLabel.Text = 'Nhập Hàm Số';
            app.functionInput = uieditfield(app.oHmTab, 'text');
            app.functionInput.Value = 'x^2';
            app.NhpbchLabel = uilabel(app.oHmTab);
            app.NhpbchLabel.Text = 'Nhập Bước h';
            app.hInput = uieditfield(app.oHmTab, 'numeric');
            app.hInput.Value = 1;
            app.InputChoiceLabel = uilabel(app.oHmTab);
            app.InputChoiceLabel.Text = 'Giá Trị Đầu Vào';
            app.inputChoice = uidropdown(app.oHmTab);
            app.inputChoice.Items = {'ValuesXY', 'Function'};
            app.inputChoice.Value = 'ValuesXY';
            app.GitrsaisLabel = uilabel(app.oHmTab);
            app.GitrsaisLabel.Text = 'Giá Trị Sai Số';
            app.accuracyChoice = uidropdown(app.oHmTab);
            app.accuracyChoice.Items = {'1', '2'};
            app.accuracyChoice.Value = '1';
            app.ChnPPohmLabel = uilabel(app.oHmTab);
            app.ChnPPohmLabel.Text = 'Chọn PP Đạo Hàm';
            app.methodChoice = uidropdown(app.oHmTab);
            app.methodChoice.Items = {'Forward', 'Backward', 'Central'};
            app.methodChoice.Value = 'Forward';
            app.GitrcntnhLabel = uilabel(app.oHmTab);
            app.GitrcntnhLabel.Text = 'Giá Trị Cần Tính ';
            app.evaluationPointInput = uieditfield(app.oHmTab, 'numeric');
            app.evaluationPointInput.Value = 3;
            app.NhpdliuxLabel = uilabel(app.oHmTab);
            app.NhpdliuxLabel.Text = 'Nhập Dữ Liệu x';
            app.xInput = uieditfield(app.oHmTab, 'text');
            app.xInput.Value = '1 2 3 4';
            app.KtquLabel = uilabel(app.oHmTab);
            app.KtquLabel.Text = 'Kết Quả';
            app.textResult = uitextarea(app.oHmTab);
            app.Label7_4 = uilabel(app.oHmTab);
            app.Label7_4.Text = 'Giao Diện Đạo Hàm ';
            app.CniviPPCentralNuchnsaislOh2thchn1NuchnsaislOh4thchn2Label = uilabel(app.oHmTab);
            app.CniviPPCentralNuchnsaislOh2thchn1NuchnsaislOh4thchn2Label.Text = {'Còn đối với PP Central'; ' - Nếu chọn sai số là O(h^2) thì chọn 1'; ' - Nếu chọn sai số là O(h^4) thì chọn 2'};
            app.TchPhnTab = uitab(app.TabGroup);
            app.TchPhnTab.Title = 'Tích Phân';
            app.Label3 = uilabel(app.TchPhnTab);
            app.Label3.Text = 'Hoặc';
            app.ButtonGroup = uibuttongroup(app.TchPhnTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Chọn phương pháp tính tích phân';
            app.Hinhthang = uiradiobutton(app.ButtonGroup);
            app.Hinhthang.Text = 'Phương pháp hình thang';
            app.Hinhthang.Value = true;
            app.Simpson13 = uiradiobutton(app.ButtonGroup);
            app.Simpson13.Text = 'Phương pháp Simpson 1/3';
            app.Simpson38 = uiradiobutton(app.ButtonGroup);
            app.Simpson38.Text = 'Phương pháp Simpson 3/8';
            app.Label7 = uilabel(app.TchPhnTab);
            app.Label7.Text = 'Giao Diện Tính Tích Phân ';
            app.Label2 = uilabel(app.TchPhnTab);
            app.Label2.Text = 'Nhập Hàm Số';
            app.Hamso = uieditfield(app.TchPhnTab, 'text');
            app.Hamso.Value = 'x^2';
            app.LabelEditField6 = uilabel(app.TchPhnTab);
            app.LabelEditField6.Text = 'Kết Quả';
            app.nghiem = uieditfield(app.TchPhnTab, 'text');
            app.LabelEditField = uilabel(app.TchPhnTab);
            app.LabelEditField.Text = 'Nhập Dữ Liệu x';
            app.mangx = uieditfield(app.TchPhnTab, 'text');
            app.mangx.Value = '1 2 3 4';
            app.Label6 = uilabel(app.TchPhnTab);
            app.Label6.Text = 'Nhập Dữ Liệu x';
            app.mangy = uieditfield(app.TchPhnTab, 'text');
            app.mangy.Value = '2 4 8 16';
            app.LabelEditField7 = uilabel(app.TchPhnTab);
            app.LabelEditField7.Text = 'Nhập Cận Trên';
            app.cantren = uieditfield(app.TchPhnTab, 'text');
            app.cantren.Value = '1';
            app.Label8 = uilabel(app.TchPhnTab);
            app.Label8.Text = 'Nhập Cận Dưới';
            app.canduoi = uieditfield(app.TchPhnTab, 'text');
            app.canduoi.Value = '0';
            app.Label9 = uilabel(app.TchPhnTab);
            app.Label9.Text = 'Nhập N';
            app.khoangchia = uieditfield(app.TchPhnTab, 'text');
            app.khoangchia.Value = '12';
            app.TnhButton = uibutton(app.TchPhnTab, 'push');
            app.TnhButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.TnhButton.Text = 'Tính';
            app.GiiThiuNhmTab = uitab(app.TabGroup);
            app.GiiThiuNhmTab.Title = 'Giới Thiệu Nhóm';
            app.ThnhVinNhmButtonGroup = uibuttongroup(app.GiiThiuNhmTab);
            app.ThnhVinNhmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThnhVinNhmButtonGroupSelectionChanged, true);
            app.ThnhVinNhmButtonGroup.Title = 'Thành Viên Nhóm';
            app.LVM = uiradiobutton(app.ThnhVinNhmButtonGroup);
            app.LVM.Text = 'Lê Văn Minh';
            app.LVM.Value = true;
            app.NTPQ = uiradiobutton(app.ThnhVinNhmButtonGroup);
            app.NTPQ.Text = 'Nguyễn Thị Phương Quỳnh';
            app.VBN = uiradiobutton(app.ThnhVinNhmButtonGroup);
            app.VBN.Text = 'Võ Bích Ngọc';
            app.NTL = uiradiobutton(app.ThnhVinNhmButtonGroup);
            app.NTL.Text = 'Nguyễn Thành Lộc';
            app.TVT = uiradiobutton(app.ThnhVinNhmButtonGroup);
            app.TVT.Text = 'Trần Vy Thanh';
            app.LT = uiradiobutton(app.ThnhVinNhmButtonGroup);
            app.LT.Text = 'Lê Tuấn';
            app.Image = uiimage(app.GiiThiuNhmTab);
            app.QuQunEditFieldLabel = uilabel(app.GiiThiuNhmTab);
            app.QuQunEditFieldLabel.Text = 'Quê Quán';
            app.quequan = uieditfield(app.GiiThiuNhmTab, 'text');
            app.PhnCngNhimvEditFieldLabel = uilabel(app.GiiThiuNhmTab);
            app.PhnCngNhimvEditFieldLabel.Text = 'Phân Công Nhiệm vụ';
            app.nhiemvu = uieditfield(app.GiiThiuNhmTab, 'text');
            app.NmSinhEditFieldLabel = uilabel(app.GiiThiuNhmTab);
            app.NmSinhEditFieldLabel.Text = 'Năm Sinh';
            app.namsinh = uieditfield(app.GiiThiuNhmTab, 'text');
            app.MSSinhVinEditFieldLabel = uilabel(app.GiiThiuNhmTab);
            app.MSSinhVinEditFieldLabel.Text = 'Mã Số Sinh Viên';
            app.mssv = uieditfield(app.GiiThiuNhmTab, 'text');
            app.nhiDinLabel = uilabel(app.GiiThiuNhmTab);
            app.nhiDinLabel.Text = 'Ảnh Đại Diện';
            app.SThchEditFieldLabel = uilabel(app.GiiThiuNhmTab);
            app.SThchEditFieldLabel.Text = 'Sở Thích';
            app.SThchEditField = uieditfield(app.GiiThiuNhmTab, 'text');
            app.GmailcnhnEditFieldLabel = uilabel(app.GiiThiuNhmTab);
            app.GmailcnhnEditFieldLabel.Text = 'Gmail cá nhân';
            app.GmailcnhnEditField = uieditfield(app.GiiThiuNhmTab, 'text');
            app.imMnhEditFieldLabel = uilabel(app.GiiThiuNhmTab);
            app.imMnhEditFieldLabel.Text = 'Điểm Mạnh';
            app.imMnhEditField = uieditfield(app.GiiThiuNhmTab, 'text');
            app.cMNguynVngEditFieldLabel = uilabel(app.GiiThiuNhmTab);
            app.cMNguynVngEditFieldLabel.Text = 'Ước Mơ/ Nguyện Vọng';
            app.cMNguynVngEditField = uieditfield(app.GiiThiuNhmTab, 'text');
            app.Label7_3 = uilabel(app.GiiThiuNhmTab);
            app.Label7_3.Text = 'Sáu Anh Em Siêu Nhân';
            app.TmThngTinButton = uibutton(app.GiiThiuNhmTab, 'push');
            app.TmThngTinButton.ButtonPushedFcn = createCallbackFcn(app, @ThnhVinNhmButtonGroupSelectionChanged, true);
            app.TmThngTinButton.Text = 'Tìm Thông Tin';
            app.imTnhGiLabel = uilabel(app.GiiThiuNhmTab);
            app.imTnhGiLabel.Text = 'Điểm Tự Đánh Giá';
            app.diem = uieditfield(app.GiiThiuNhmTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOANPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end