classdef DoAnTichphan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure                % UI Figure
        Label2          matlab.ui.control.Label         % Nhập hàm số
        Hamso           matlab.ui.control.EditField    
        Label3          matlab.ui.control.Label         % Hoặc
        ButtonGroup     matlab.ui.container.ButtonGroup % Chọn phương pháp ...
        Hinhthang       matlab.ui.control.RadioButton   % Phương pháp hình ...
        Simpson13       matlab.ui.control.RadioButton   % Phương pháp Simps...
        Simpson38       matlab.ui.control.RadioButton   % Phương pháp Simps...
        LabelEditField6 matlab.ui.control.Label         % Kết quả
        nghiem          matlab.ui.control.EditField    
        LabelEditField  matlab.ui.control.Label         % Nhập dữ liệu x
        mangx           matlab.ui.control.EditField    
        Label6          matlab.ui.control.Label         % Nhập dữ liệu y
        mangy           matlab.ui.control.EditField    
        Label7          matlab.ui.control.Label         % Giao diện tính tí...
        LabelEditField7 matlab.ui.control.Label         % Nhập cận trên
        cantren         matlab.ui.control.EditField    
        Label8          matlab.ui.control.Label         % Nhập cận dưới
        canduoi         matlab.ui.control.EditField    
        Label9          matlab.ui.control.Label         % Nhập N
        khoangchia      matlab.ui.control.EditField    
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % ButtonGroup selection change function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
                    xa = app.mangx.Value;
                    xa = str2num(xa);
                    ya = app.mangy.Value;
                    ya = str2num(ya);
                    a = app.cantren.Value;
                    a = str2num(a);
                    b = app.canduoi.Value;
                    b = str2num(b);
                    N = app.khoangchia.Value;
                    N = str2num(N);
            if app.Hinhthang.Value == 1
                    if xa ~= NaN & ya ~= NaN
                        KQ = trapz(xa,ya);
                    else
                       fx = app.Hamso.Value;
                       fx = str2func(['@(x)',fx]);
                       [KQ] = tichphanhinhthang (fx, a, b, N)
                    end
                    app.nghiem.Value = num2str(KQ);
            
            elseif app.Simpson13.Value == 1
                    if xa ~= NaN & ya ~= NaN
                        KQ = trapz(xa,ya);
                    else
                    fx = app.Hamso.Value;
                    fx = str2func(['@(x)',fx]);
                        if mod(N,2)~=0
                        app.nghiem.Value = char('Sai khoang chia');
                        return
                        end
                    [KQ] = tichphanSimpson(fx, a, b, N)
                    end
                    app.nghiem.Value = num2str(KQ);
        
            elseif app.Simpson38.Value == 1
                    if xa ~= NaN & ya ~= NaN
                        KQ = trapz(xa,ya);
                    else
                    fx = app.Hamso.Value;
                    fx = str2func(['@(x)',fx]); 
                    if mod(N,3)~=0
                        app.nghiem.Value = char('Sai khoang chia');
                        return
                    end
                    [KQ] = tichphanSimpson38(fx, a, b, N)
                    end
                    app.nghiem.Value = num2str(KQ);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Nhập hàm số';
            app.Hamso = uieditfield(app.UIFigure, 'text');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Hoặc';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Chọn phương pháp tính tích phân';
            app.ButtonGroup.Units = 'pixels';
            app.Hinhthang = uiradiobutton(app.ButtonGroup);
            app.Hinhthang.Text = 'Phương pháp hình thang';
            app.Hinhthang.Value = true;
            app.Simpson13 = uiradiobutton(app.ButtonGroup);
            app.Simpson13.Text = 'Phương pháp Simpson 1/3';
            app.Simpson38 = uiradiobutton(app.ButtonGroup);
            app.Simpson38.Text = 'Phương pháp Simpson 3/8';
            app.LabelEditField6 = uilabel(app.UIFigure);
            app.LabelEditField6.Text = 'Kết quả';
            app.nghiem = uieditfield(app.UIFigure, 'text');
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Nhập dữ liệu x';
            app.mangx = uieditfield(app.UIFigure, 'text');
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Nhập dữ liệu y';
            app.mangy = uieditfield(app.UIFigure, 'text');
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Giao diện tính tích phân ';
            app.LabelEditField7 = uilabel(app.UIFigure);
            app.LabelEditField7.Text = 'Nhập cận trên';
            app.cantren = uieditfield(app.UIFigure, 'text');
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'Nhập cận dưới';
            app.canduoi = uieditfield(app.UIFigure, 'text');
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'Nhập N';
            app.khoangchia = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = DoAnTichphan()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end