classdef Giaodiendaoham < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LuNuchnsaislOhthchn1NuchnsaislOh2thchn2Label  matlab.ui.control.Label
        GIAODINOHMLabel       matlab.ui.control.Label
        textResult            matlab.ui.control.TextArea
        KtquLabel             matlab.ui.control.Label
        ThchinphptnhButton    matlab.ui.control.Button
        evaluationPointInput  matlab.ui.control.NumericEditField
        GitrcntnhLabel        matlab.ui.control.Label
        methodChoice          matlab.ui.control.DropDown
        ChnPPohmLabel         matlab.ui.control.Label
        accuracyChoice        matlab.ui.control.DropDown
        GitrsaisLabel         matlab.ui.control.Label
        inputChoice           matlab.ui.control.DropDown
        InputChoiceLabel      matlab.ui.control.Label
        hInput                matlab.ui.control.NumericEditField
        NhpbchLabel           matlab.ui.control.Label
        functionInput         matlab.ui.control.EditField
        FunctionInputLabel    matlab.ui.control.Label
        fxInput               matlab.ui.control.EditField
        NhpLabel              matlab.ui.control.Label
        xInput                matlab.ui.control.EditField
        NhpdliuxLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ThchinphptnhButton
        function ThchinphptnhButtonPushed(app, event)

    % Get input values from UI components
    if strcmp(app.inputChoice.Value, 'ValuesXY')
        x = str2num(app.xInput.Value);
        fx = str2num(app.fxInput.Value);
        order_of_accuracy = str2num(app.accuracyChoice.Value);
        method = app.methodChoice.Value;
        evaluation_point = app.evaluationPointInput.Value;


	% Calculate derivative based on chosen method
    if strcmp(method, 'Forward')
        derivative = forwardDifferenceDerivativexy(x, fx, evaluation_point, order_of_accuracy);
    elseif strcmp(method, 'Backward')
        derivative = backwardDifferenceDerivativeDerivativexy(x, fx, evaluation_point, order_of_accuracy);
    elseif strcmp(method, 'Central')
        derivative = centralDifferenceDerivativeDerivativexy(x, fx, evaluation_point, order_of_accuracy);
    else
        error('Invalid method selection.');
    end


    % Display the result
    app.textResult.Value = sprintf('Đạo hàm tại %.2f là: %.4f', evaluation_point, derivative);

    else

        func_str = app.functionInput.Value;
        func = str2func(['@(x)' func_str]); % Thêm @(x) để tạo hàm số từ chuỗi
        
        h = app.hInput.Value;
        order_of_accuracy = str2num(app.accuracyChoice.Value);
        method = app.methodChoice.Value;
        evaluation_point = app.evaluationPointInput.Value;


% Calculate derivative based on chosen method
    if strcmp(method, 'Forward')
        derivative = forwardDifferenceDerivative(func, evaluation_point, h, order_of_accuracy);
    elseif strcmp(method, 'Backward')
        derivative = backwardDifferenceDerivative(func, evaluation_point, h, order_of_accuracy);
    elseif strcmp(method, 'Central')
        derivative = centralDifferenceDerivative(func, evaluation_point, h, order_of_accuracy);
    else
        error('Invalid method selection.');
    end

    % Display the result
    app.textResult.Value = sprintf('Đạo hàm tại %.2f là: %.4f', evaluation_point, derivative);
    
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NhpdliuxLabel = uilabel(app.UIFigure);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.xInput = uieditfield(app.UIFigure, 'text');
            app.NhpLabel = uilabel(app.UIFigure);
            app.NhpLabel.Text = 'Nhập dữ liệu y';
            app.fxInput = uieditfield(app.UIFigure, 'text');
            app.FunctionInputLabel = uilabel(app.UIFigure);
            app.FunctionInputLabel.Text = 'Nhập hàm số';
            app.functionInput = uieditfield(app.UIFigure, 'text');
            app.NhpbchLabel = uilabel(app.UIFigure);
            app.NhpbchLabel.Text = 'Nhập bước h';
            app.hInput = uieditfield(app.UIFigure, 'numeric');
            app.InputChoiceLabel = uilabel(app.UIFigure);
            app.InputChoiceLabel.Text = 'Giá trị đầu vào';
            app.inputChoice = uidropdown(app.UIFigure);
            app.inputChoice.Items = {'ValuesXY', 'Function'};
            app.inputChoice.Value = 'ValuesXY';
            app.GitrsaisLabel = uilabel(app.UIFigure);
            app.GitrsaisLabel.Text = 'Giá trị sai số';
            app.accuracyChoice = uidropdown(app.UIFigure);
            app.accuracyChoice.Items = {'1', '2'};
            app.accuracyChoice.Value = '1';
            app.ChnPPohmLabel = uilabel(app.UIFigure);
            app.ChnPPohmLabel.Text = 'Chọn PP đạo hàm';
            app.methodChoice = uidropdown(app.UIFigure);
            app.methodChoice.Items = {'Forward', 'Backward', 'Central'};
            app.methodChoice.Value = 'Forward';
            app.GitrcntnhLabel = uilabel(app.UIFigure);
            app.GitrcntnhLabel.Text = 'Giá trị cần tính ';
            app.evaluationPointInput = uieditfield(app.UIFigure, 'numeric');
            app.ThchinphptnhButton = uibutton(app.UIFigure, 'push');
            app.ThchinphptnhButton.ButtonPushedFcn = createCallbackFcn(app, @ThchinphptnhButtonPushed, true);
            app.ThchinphptnhButton.Text = 'Thực hiện phép tính';
            app.KtquLabel = uilabel(app.UIFigure);
            app.KtquLabel.Text = 'Kết quả';
            app.textResult = uitextarea(app.UIFigure);
            app.GIAODINOHMLabel = uilabel(app.UIFigure);
            app.GIAODINOHMLabel.Text = 'GIAO DIỆN ĐẠO HÀM';
            app.LuNuchnsaislOhthchn1NuchnsaislOh2thchn2Label = uilabel(app.UIFigure);
            app.LuNuchnsaislOhthchn1NuchnsaislOh2thchn2Label.Text = {'Lưu ý'; ' - Nếu chọn sai số là O(h) thì chọn 1'; ' - Nếu chọn sai số là O(h^2) thì chọn 2'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Giaodiendaoham

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end