classdef Giaodienhoiquy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        nhpmngxEditFieldLabel    matlab.ui.control.Label
        nhapmangX                matlab.ui.control.EditField
        nhpmngyEditFieldLabel    matlab.ui.control.Label
        nhapmangY                matlab.ui.control.EditField
        PhngphpnisuyButtonGroup  matlab.ui.container.ButtonGroup
        TuyentinhButton          matlab.ui.control.RadioButton
        logaritButton            matlab.ui.control.RadioButton
        hammuButton              matlab.ui.control.RadioButton
        nhpgitrxEditFieldLabel   matlab.ui.control.Label
        gtriXEditField           matlab.ui.control.NumericEditField
        TinhvaveButton           matlab.ui.control.Button
        ktquEditFieldLabel       matlab.ui.control.Label
        ketquaEditField          matlab.ui.control.NumericEditField
        athchiquyEditFieldLabel  matlab.ui.control.Label
        dathucEditField          matlab.ui.control.EditField
    end

    methods (Access = private)

        % Callback function: PhngphpnisuyButtonGroup, TinhvaveButton
        function TinhvaveButtonPushed(app, event)
           x_array = str2num(app.nhapmangX.Value);
           y_array = str2num(app.nhapmangY.Value);
           giatrix = app.gtriXEditField.Value;
           
           if(app.TuyentinhButton.Value == 1)
               [fx, kq] = Hoiquybac1(x_array, y_array, giatrix);
           elseif(app.logaritButton.Value == 1)
               [fx, kq] = Hoiquylogarit(x_array, y_array, giatrix);
           else
               [fx, kq] = Hoiquyhammu(x_array, y_array, giatrix);
           end
           fx1 = str2func(['@(x)', char(fx)]);
           x = x_array(1):0.1:x_array(length(x_array));
           j = 1;
           for i = x_array(1):0.1:x_array(length(x_array))
               y(j) = fx1(i);
               j = j+1;
           end
           plot(app.UIAxes, x, y);
           app.dathucEditField.Value = char(fx);
           if isnumeric(kq) && isscalar(kq) % Check if kq is a numeric scalar
               app.ketquaEditField.Value = double(kq);
           elseif ischar(kq) % Check if kq is a character vector
               app.ketquaEditField.Value = kq;
           else
               % Handle other cases or display an error message
               disp('Error: Unsupported data type for kq');
           end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.nhpmngxEditFieldLabel = uilabel(app.UIFigure);
            app.nhpmngxEditFieldLabel.Text = 'nhập mảng x';
            app.nhapmangX = uieditfield(app.UIFigure, 'text');
            app.nhapmangX.Value = '1 2 3 4 5 6 7 8 9';
            app.nhpmngyEditFieldLabel = uilabel(app.UIFigure);
            app.nhpmngyEditFieldLabel.Text = 'nhập mảng y';
            app.nhapmangY = uieditfield(app.UIFigure, 'text');
            app.nhapmangY.Value = '1 1.5 2 3 4 5 8 10 13';
            app.PhngphpnisuyButtonGroup = uibuttongroup(app.UIFigure);
            app.PhngphpnisuyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TinhvaveButtonPushed, true);
            app.PhngphpnisuyButtonGroup.Title = 'Phương pháp nội suy';
            app.TuyentinhButton = uiradiobutton(app.PhngphpnisuyButtonGroup);
            app.TuyentinhButton.Text = 'tuyến tính';
            app.TuyentinhButton.Value = true;
            app.logaritButton = uiradiobutton(app.PhngphpnisuyButtonGroup);
            app.logaritButton.Text = 'logarit';
            app.hammuButton = uiradiobutton(app.PhngphpnisuyButtonGroup);
            app.hammuButton.Text = 'hàm mũ';
            app.nhpgitrxEditFieldLabel = uilabel(app.UIFigure);
            app.nhpgitrxEditFieldLabel.Text = 'nhập giá trị x';
            app.gtriXEditField = uieditfield(app.UIFigure, 'numeric');
            app.gtriXEditField.Value = 3.2;
            app.TinhvaveButton = uibutton(app.UIFigure, 'push');
            app.TinhvaveButton.ButtonPushedFcn = createCallbackFcn(app, @TinhvaveButtonPushed, true);
            app.TinhvaveButton.Text = 'Tính và vẽ';
            app.athchiquyEditFieldLabel = uilabel(app.UIFigure);
            app.athchiquyEditFieldLabel.Text = 'Đa thức hồi quy';
            app.dathucEditField = uieditfield(app.UIFigure, 'text');
            app.ktquEditFieldLabel = uilabel(app.UIFigure);
            app.ktquEditFieldLabel.Text = 'kết quả';
            app.ketquaEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Giaodienhoiquy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end