classdef NOISUY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KtqunisuyLabel                matlab.ui.control.Label
        TinhVeButton                  matlab.ui.control.Button
        KQ                            matlab.ui.control.Label
        Nhapx                         matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        Chon                          matlab.ui.control.DropDown
        PhngphpLabel                  matlab.ui.control.Label
        mangy                         matlab.ui.control.EditField
        NhpmngyLabel                  matlab.ui.control.Label
        mangx                         matlab.ui.control.EditField
        NhpmngxLabel                  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TinhVeButton
        function TinhVeButtonPushed(app, event)
            chon = app.Chon.Value;
            xa = str2num(app.mangx.Value);
            ya = str2num(app.mangy.Value);
            x  = app.Nhapx.Value;
            if (chon ==  "Nội suy Newton")
                [result] = NewtonInterpolation(xa, ya, x);
                app.KQ.Text = num2str(result);
                plot(app.UIAxes,xa,ya);  
            elseif (chon == "Nội suy Lagrange")
                [result] = Lagrange(xa, ya, x);
                app.KQ.Text = num2str(result);
                plot(app.UIAxes,xa,ya);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'NỘI SUY')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NhpmngxLabel = uilabel(app.UIFigure);
            app.NhpmngxLabel.Text = 'Nhập mảng x';
            app.mangx = uieditfield(app.UIFigure, 'text');
            app.mangx.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpmngyLabel = uilabel(app.UIFigure);
            app.NhpmngyLabel.Text = 'Nhập mảng y';
            app.mangy = uieditfield(app.UIFigure, 'text');
            app.mangy.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.PhngphpLabel = uilabel(app.UIFigure);
            app.PhngphpLabel.Text = {'Chọn      '; 'phương pháp'; ''};
            app.Chon = uidropdown(app.UIFigure);
            app.Chon.Items = {'Nội suy Newton', 'Nội suy Lagrange'};
            app.Chon.Value = 'Nội suy Newton';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhpgitrcnnisuyEditFieldLabel.Text = {'Nhập giá trị'; 'cần nội suy'};
            app.Nhapx = uieditfield(app.UIFigure, 'numeric');
            app.Nhapx.Value = 0.14;
            app.KQ = uilabel(app.UIFigure);
            app.KQ.Text = '';
            app.TinhVeButton = uibutton(app.UIFigure, 'push');
            app.TinhVeButton.ButtonPushedFcn = createCallbackFcn(app, @TinhVeButtonPushed, true);
            app.TinhVeButton.Text = 'Tính và vẽ';
            app.KtqunisuyLabel = uilabel(app.UIFigure);
            app.KtqunisuyLabel.Text = 'Kết quả nội suy:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NOISUY

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end