classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CUOIEditField                   matlab.ui.control.NumericEditField
        GIIHNCUICAMNGXLabel             matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        SAISOEditField                  matlab.ui.control.NumericEditField
        SAISEditFieldLabel              matlab.ui.control.Label
        DOANCONNEditField               matlab.ui.control.NumericEditField
        SONCONNEditFieldLabel           matlab.ui.control.Label
        DAUXEditField                   matlab.ui.control.NumericEditField
        GIIHNUCAMNGXLabel               matlab.ui.control.Label
        PTVIPHANEditField               matlab.ui.control.EditField
        NHPPHNGTRNHVIPHNEditFieldLabel  matlab.ui.control.Label
        Y0EditField                     matlab.ui.control.NumericEditField
        IUKINOVOY0EditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end

        % Callback function: Button, ListBox
        function ButtonPushed(app, event)
            value = app.ListBox.Value;
            if (value == "Ơ LE")
                f = app.PTVIPHANEditField.Value
                x0 = app.DAUXEditField.Value
                xn = app.CUOIEditField.Value
                y0 = app.Y0EditField.Value
                N = app.DOANCONNEditField.Value
                fxy = str2func(['@(x, y) ', f])
                [x, y] = ole(fxy, x0, xn, y0, N)
    plot(app.UIAxes , x,y)
            elseif (value == "HIỆN ẨN HÌNH THANG")
                fxy = app.PTVIPHANEditField
                x0 = app.DAUXEditField
                xn = app.CUOIXEditField
                y0 = app.Y0EditField
                N = app.DOANCONNEditField
                e = app.SAISOEditField
               [x,y] = hienanhinhthang(fxy,x0,xn,y0,N,e)
    plot(app.UIAxes , x,y)
    elseif (value == "HIỆN ẨN TRUNG ĐIỂM")
                fxy = app.PTVIPHANEditField
                x0 = app.DAUXEditField
                xn = app.CUOIXEditField
                y0 = app.Y0EditField
                N = app.DOANCONNEditField
                [x, y] = hienantrungdiem(fxy, x0, xn, y0, N)
    plot(app.UIAxes , x,y)
            else 
                fxy = app.PTVIPHANEditField
                x0 = app.DAUXEditField
                xn = app.CUOIXEditField
                y0 = app.Y0EditField
                N = app.DOANCONNEditField
                [x, y] = RK(fxy, x0, xn, y0, N)
    plot(app.UIAxes , x,y)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IUKINOVOY0EditFieldLabel = uilabel(app.UIFigure);
            app.IUKINOVOY0EditFieldLabel.Text = 'ĐIỀU KIỆN ĐÀO VÀO Y(0)';
            app.Y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y0EditField.Value = 1;
            app.NHPPHNGTRNHVIPHNEditFieldLabel = uilabel(app.UIFigure);
            app.NHPPHNGTRNHVIPHNEditFieldLabel.Text = 'NHẬP PHƯƠNG TRÌNH VI PHÂN';
            app.PTVIPHANEditField = uieditfield(app.UIFigure, 'text');
            app.PTVIPHANEditField.Value = '(1-x)*y';
            app.GIIHNUCAMNGXLabel = uilabel(app.UIFigure);
            app.GIIHNUCAMNGXLabel.Text = 'GIỚI HẠN ĐẦU  CỦA MẢNG X';
            app.DAUXEditField = uieditfield(app.UIFigure, 'numeric');
            app.SONCONNEditFieldLabel = uilabel(app.UIFigure);
            app.SONCONNEditFieldLabel.Text = 'SỐ ĐOẠN CON N';
            app.DOANCONNEditField = uieditfield(app.UIFigure, 'numeric');
            app.DOANCONNEditField.Value = 25;
            app.SAISEditFieldLabel = uilabel(app.UIFigure);
            app.SAISEditFieldLabel.Text = 'SAI SỐ';
            app.SAISOEditField = uieditfield(app.UIFigure, 'numeric');
            app.SAISOEditField.Value = 0.001;
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Ơ LE', 'HIỆN ẨN HÌNH THANG', 'HIỆN ẨN TRUNG ĐIỂM', 'R-K'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ListBox.Value = 'Ơ LE';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.GIIHNCUICAMNGXLabel = uilabel(app.UIFigure);
            app.GIIHNCUICAMNGXLabel.Text = 'GIỚI HẠN CUỐI CỦA MẢNG X';
            app.CUOIEditField = uieditfield(app.UIFigure, 'numeric');
            app.CUOIEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end