classdef timNghiem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SaiSoEditField        matlab.ui.control.EditField
        SaisEditFieldLabel    matlab.ui.control.Label
        bEditField            matlab.ui.control.EditField
        bEditFieldLabel       matlab.ui.control.Label
        aEditField            matlab.ui.control.EditField
        aEditFieldLabel       matlab.ui.control.Label
        fpxEditField          matlab.ui.control.EditField
        fpxEditFieldLabel     matlab.ui.control.Label
        PhngphpButtonGroup    matlab.ui.container.ButtonGroup
        NewtonButton          matlab.ui.control.RadioButton
        LapButton             matlab.ui.control.RadioButton
        CdButton              matlab.ui.control.RadioButton
        KtqunghimLabel        matlab.ui.control.Label
        SoLanLapEditField     matlab.ui.control.EditField
        KetQuaEditField       matlab.ui.control.EditField
        SlnlpLabel            matlab.ui.control.Label
        KetQuaButton          matlab.ui.control.Button
        PhuongTrinhEditField  matlab.ui.control.EditField
        NhpphngtrnhfxLabel    matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        % Phương pháp chia đôi
      function [nghiem, solanlap] = chiadoi(~, f, a, b, saiso)
    solanlap = 0;
 while abs(b-a) >= saiso
     solanlap = solanlap + 1;
     c = (a+b)/2;
 if f(c)*f(a) < 0
    b = c;
 elseif f(c)*f(a) > 0
    a = c;
 else
 break;
 end
 end
     nghiem = c;
 end
        % Phương pháp lặp
function [nghiem, solanlap] = lap(~,fp,a,b,saiso)
     x0 = a;
     nghiem = b;
     solanlap = 0;
 while abs(nghiem - x0) >= saiso
     solanlap = solanlap + 1;
     x0 = nghiem;
     nghiem = fp(x0);
 end
end

%Phương pháp tiếp tuyến
function [nghiem,solanlap] = tieptuyen(~,f,a,b,saiso)
 syms x;
     fd1 = str2func(['@(x)' char(diff(f(x)))]);
     fd2 = str2func(['@(x)' char(diff(fd1(x)))]);
     nostop = 1;
 if nostop
    x0 = a;
 while f(x0)*fd2(x0) <= 0
 if f(x0) == 0
     nghiem = x0;
     solanlap = 0;
 return;
 else
      x0 = (x0+b)/2;
 end
 end
      nghiem = x0 - f(x0)/fd1(x0);
      solanlap = 0;
 while abs(nghiem-x0) >= saiso
      solanlap = solanlap + 1;
      x0 = nghiem;
      nghiem = x0 - f(x0)/fd1(x0);
 end
 end
 end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KetQuaButton
        function KetQuaButtonPushed(app, event)

 chiadoi = app.CdButton.Value;
 lap = app.LapButton.Value;
 newton = app.NewtonButton.Value;

 f = app.PhuongTrinhEditField.Value;
 f = str2func(['@(x)' f]);

 fp = app.fpxEditField.Value;
 fp = str2func(['@(x)' fp]);

 a = app.aEditField.Value;
 a = str2num(a);

 b = app.bEditField.Value;
 b = str2num(b);

 saiso = app.SaiSoEditField.Value;
 saiso = str2num(saiso);

 x_array = linspace(a,b);
 y_array = zeros(1, length(x_array));
 yp_array = zeros(1, length(x_array));

 for i = 1:length(x_array) - 1
 y_array(i) = f(x_array(i));
 yp_array(i) = fp(x_array(i));
 end

 if chiadoi == 1
     [nghiem, solanlap] = app.chiadoi(f, a, b, saiso)
     app.KetQuaEditField.Value = num2str(nghiem);
     app.SoLanLapEditField.Value = num2str(solanlap);
     plot(app.UIAxes,x_array, y_array);
     title(app.UIAxes, 'Phương pháp chia đôi');
 elseif lap == 1
     [nghiem, solanlap] = app.lap(fp,a,b,saiso);
     app.KetQuaEditField.Value = num2str(nghiem);
     app.SoLanLapEditField.Value = num2str(solanlap);
     plot(app.UIAxes,x_array, yp_array);
     title(app.UIAxes, 'Phương pháp lặp');
 else
     [nghiem,solanlap] = app.tieptuyen(f,a,b,saiso);
     app.KetQuaEditField.Value = num2str(nghiem);
     app.SoLanLapEditField.Value = num2str(solanlap);
     plot(app.UIAxes,x_array, y_array);
     title(app.UIAxes, 'Phương pháp tiếp tuyến');
 end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhfxLabel = uilabel(app.UIFigure);
            app.NhpphngtrnhfxLabel.Text = 'Nhập phương trình fx';
            app.PhuongTrinhEditField = uieditfield(app.UIFigure, 'text');
            app.KetQuaButton = uibutton(app.UIFigure, 'push');
            app.KetQuaButton.ButtonPushedFcn = createCallbackFcn(app, @KetQuaButtonPushed, true);
            app.KetQuaButton.Text = {'Kết quả nghiệm và vẽ'; ''};
            app.SlnlpLabel = uilabel(app.UIFigure);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.KetQuaEditField = uieditfield(app.UIFigure, 'text');
            app.SoLanLapEditField = uieditfield(app.UIFigure, 'text');
            app.KtqunghimLabel = uilabel(app.UIFigure);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm';
            app.PhngphpButtonGroup = uibuttongroup(app.UIFigure);
            app.PhngphpButtonGroup.Title = 'Phương pháp';
            app.CdButton = uiradiobutton(app.PhngphpButtonGroup);
            app.CdButton.Text = {'Chia đôi'; ''; ''};
            app.CdButton.Value = true;
            app.LapButton = uiradiobutton(app.PhngphpButtonGroup);
            app.LapButton.Text = {'Lặp'; ''};
            app.NewtonButton = uiradiobutton(app.PhngphpButtonGroup);
            app.NewtonButton.Text = {'Newton'; ''};
            app.fpxEditFieldLabel = uilabel(app.UIFigure);
            app.fpxEditFieldLabel.Text = 'fp(x)';
            app.fpxEditField = uieditfield(app.UIFigure, 'text');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.SaisEditFieldLabel = uilabel(app.UIFigure);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.SaiSoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timNghiem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end